classdef GUIPotentiometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PWMSlider                  matlab.ui.control.Slider
        PWMSliderLabel             matlab.ui.control.Label
        ModeSwitch                 matlab.ui.control.Switch
        ModeSwitchLabel            matlab.ui.control.Label
        EnableSwitch               matlab.ui.control.Switch
        EnableSwitchLabel          matlab.ui.control.Label
        DataCollectionSwitch       matlab.ui.control.ToggleSwitch
        DataCollectionSwitchLabel  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        plotting% Description

        animatedLine2

        PWMValue % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataCollectionSwitch
        function DataCollectionSwitchValueChanged(app, event)
            app.plotting = app.DataCollectionSwitch.Value;
            if app.plotting == "On"
                
                %Start taking data, open serial port
                clear serialPortLine;
                serialPortLine = serialport('COM4', 9600);
                fopen(serialPortLine);
                
                %set-up graph
                app.animatedLine2 = animatedline(app.UIAxes2);
                % set axis using get current axis(gca)
                ax = app.UIAxes2;
                
                % set y-axis limit from 0 to 5.2V
                ax.YLim = [0 5.2];
                % turn on grid

                app.UIAxes2.XAxisLocation = "bottom";
                app.UIAxes2.YAxisLocation = 'left';
                app.UIAxes2.XGrid = 'on';
                app.UIAxes2.YGrid = 'on';
                ax.XTickMode = "auto"
                ax.YLimMode = 'manual';
                ax.YLim=[0 5.2];
                ax.YTickMode = 'auto';

                while app.plotting == "On"
                    % Read current voltage value
                    inSerial = jsondecode(fscanf(serialPortLine));
                    app.PWMValue = inSerial.avgVolt;
                    % Get current time
                    t =  inSerial.time * (1/1000);
                    % Add points to animation
                    addpoints(app.animatedLine2,t,app.PWMValue);
                    % Update X-axis limit
                    ax.XLim = [t-10 t];
                    drawnow limitrate;
                end
                
                fclose(serialPortLine);

            elseif app.plotting == "Off"
                %Stop taking data, close serial port
                clearpoints(app.animatedLine2);
            end
        end

        % Value changed function: PWMSlider
        function PWMSliderValueChanged(app, event)
            value = app.PWMSlider.Value;
            display(value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataCollectionSwitchLabel = uilabel(app.LeftPanel);
            app.DataCollectionSwitchLabel.Text = 'Data Collection';
            app.DataCollectionSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.DataCollectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DataCollectionSwitchValueChanged, true);
            app.EnableSwitchLabel = uilabel(app.LeftPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ModeSwitchLabel = uilabel(app.LeftPanel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ModeSwitch.Items = {'Heat', 'Cool'};
            app.ModeSwitch.Value = 'Heat';
            app.PWMSliderLabel = uilabel(app.LeftPanel);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.LeftPanel);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.ValueChangedFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Temperature')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'T(C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'PWM')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Voltage')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIPotentiometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end